classdef QuailKit_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Fig         matlab.ui.Figure
        Group45     matlab.ui.container.Panel
        Five        matlab.ui.container.ButtonGroup
        Five_Slide  matlab.ui.control.Slider
        Five_List   matlab.ui.control.ListBox
        Five_Pop    matlab.ui.control.DropDown
        Four        matlab.ui.container.ButtonGroup
        Four_List   matlab.ui.control.ListBox
        Four_Slide  matlab.ui.control.Slider
        Four_Pop    matlab.ui.control.DropDown
        Group123    matlab.ui.container.Panel
        One         matlab.ui.container.ButtonGroup
        One_List    matlab.ui.control.ListBox
        One_Pop     matlab.ui.control.DropDown
        Two         matlab.ui.container.ButtonGroup
        Two_List    matlab.ui.control.ListBox
        Two_Slide   matlab.ui.control.Slider
        Two_Pop     matlab.ui.control.DropDown
        GroupPlay   matlab.ui.container.Panel
        Previous    matlab.ui.control.Button
        Back        matlab.ui.control.Button
        Clear       matlab.ui.control.Button
        Reset       matlab.ui.control.Button
        Skip        matlab.ui.control.Button
        Next        matlab.ui.control.Button
        QueueMode   matlab.ui.control.StateButton
        PlayMode    matlab.ui.control.Button
        Mode        matlab.ui.control.Button
        Debug       matlab.ui.control.Button
        Play        matlab.ui.control.Button
        Panel       matlab.ui.container.Panel
    end

    methods (Access = private)

        % Code that executes after component creation
        function QuailKit_OpeningFcn(app)
%             addpath(genpath(pwd));
%             app.Path.Recordings='../../Quail Call - Recordings/';
%             app.Path.Spectrograms='../../Quail Call - Shared/data/spectrograms/';
%             app.Path.Results='../../Quail Call - Shared/results/';
%             handles=SetValues(handles);
%             handles=SetAxis(handles,true);
%             handles=SetGraphics_All(handles);
%             handles=SetToolbar(handles);
%             handles=SetPlay(handles);
%             handles=Set12(handles,true);
%             handles=Set34(handles);
%             app.Graphics.Credits=Credits(...
%                 app.Graphics.Watermark,...
%                 app.Graphics.Color,true);
%             app.Graphics.Watermark=HT_Transition(...
%                 app.Graphics.Watermark,{'YLim'},...
%                 [1-app.Graphics.Watermark.Position(4),0],...
%                 app.UserData.Frames,handles);
%             handles=Wait(handles,'off');
%             app.Data.j=1;
%             handles=SetLayout(handles,app.UserData.Frames);
%             app.output = hObject;
%             guidata(hObject,handles);
        end

        % Button pushed function: Back
        function Back_Callback(app, event)
            hObject.UserData=1;
            if app.Data.j>1
                app.Data.j=app.Data.j-1;
            else
                app.Data.j=length(app.Data.Bins);
            end
            handles=HT_Compute(handles);
            handles=SetView(handles,false);
            guidata(hObject,handles);
        end

        % Button pushed function: Clear
        function Clear_Callback(app, event)
            app.Data.j=1;
            handles=HT_Compute(handles);
            handles=SetView(handles,false);
            guidata(hObject,handles);
        end

        % Button pushed function: Debug
        function Debug_Callback(app, event)
            app.Debug.UserData=rem(app.Debug.UserData+1,2);
            handles=SetGraphics_All(handles);
            handles=SetToolbar(handles);
            handles=SetPlay(handles);
            handles=Set12(handles,false);
            handles=Set34(handles);
            handles=Wait(handles,'off');
            handles=SetLayout(handles);
            guidata(hObject,handles);
        end

        % Key press function: Fig
        function Fig_KeyPressFcn(app, event)
            switch eventdata.Key
                case 'rightarrow'
                    app.Data.j=min(app.Data.j+1,length(app.Data.Bins)-1);
                case 'leftarrow'
                    app.Data.j=max(1,app.Data.j-1);
                case 'downarrow'
                    app.Data.j=min(app.Data.j+10,length(app.Data.Bins)-1);
                case 'uparrow'
                    app.Data.j=max(1,app.Data.j-10);
            end
            if ismember(eventdata.Key,{'rightarrow','leftarrow','downarrow','uparrow'})
                set(app.Graphics.Axis(3).Children,...
                    'Visible','off');
                set(app.Graphics.Axis(2).Children,...
                    'Visible','off');
                app.Graphics.Patch(1).XData=app.Data.TS.Time(...
                    [app.Data.Edges(app.Data.j),...
                    app.Data.Edges(app.Data.j+1),...
                    app.Data.Edges(app.Data.j+1),...
                    app.Data.Edges(app.Data.j)]);
                set(app.Graphics.Axis(2),...
                    'XLim',app.Graphics.Patch(1).XData(1:2));
                guidata(hObject,handles);
            end
        end

        % Key release function: Fig
        function Fig_KeyReleaseFcn(app, event)
            handles = HT_Compute(handles);
            handles=SetView(handles,false);
            guidata(app.Fig,handles);
        end

        % Size changed function: Fig
        function Fig_SizeChangedFcn(app, event)
            handles=SetLayout(handles,2);
            guidata(app.Fig,handles);
        end

        % Value changed function: Five_List
        function Five_List_Callback(app, event)
            handles = Set34(handles);
            handles=SetLayout(handles,app.UserData.Frames);
            value=hObject.UserData{hObject.Value,2};
            switch class(value)
                case 'logical'
                    app.Five_Slide.Value=double(value);
                case 'double'
                    app.Five_Slide.Value=value;
            end
            guidata(hObject,handles);
        end

        % Value changed function: Five_Pop
        function Five_Pop_Callback(app, event)
            handles = Set34(handles);
            handles=SetLayout(handles,app.UserData.Frames);
            handles=HT_Compute(handles);
            handles=SetView(handles,false);
            guidata(hObject,handles);
        end

        % Value changed function: Five_Slide
        function Five_Slide_Callback(app, event)
            switch hObject.Style
                case 'slider'
                    temp=hObject.Value;
                case 'checkbox'
                    temp=logical(hObject.Value);
            end
            app.Five_List.UserData{app.Five_List.Value,2}=temp;
            handles = Set34(handles);
            handles=SetLayout(handles);
            handles=HT_Compute(handles);
            handles=SetView(handles,false);
            guidata(hObject,handles);
        end

        % Value changed function: Four_List
        function Four_List_Callback(app, event)
            handles = Set34(handles);
            value=hObject.UserData{app.Four_Pop.Value}{2,hObject.Value};
            switch class(value)
                case 'logical'
                    app.Four_Slide.Value=double(value);
                case 'double'
                    app.Four_Slide.Value=value;
            end
            guidata(hObject,handles);
        end

        % Value changed function: Four_Pop
        function Four_Pop_Callback(app, event)
            handles = Set34(handles);
            handles=HT_Compute(handles);
            handles=SetView(handles,false);
            handles=SetLayout(handles);
            guidata(hObject,handles);
        end

        % Value changed function: Four_Slide
        function Four_Slide_Callback(app, event)
            switch hObject.Style
                case 'slider'
                    temp=hObject.Value;
                case 'checkbox'
                    temp=logical(hObject.Value);
            end
            app.Four_List.UserData{app.Four_Pop.Value}...
                {2,app.Four_List.Value}=temp;
            handles = Set34(handles);
            handles=SetLayout(handles,app.UserData.Frames);
            handles=HT_Compute(handles);
            handles=SetView(handles,false);
            guidata(hObject,handles);
        end

        % Button pushed function: Mode
        function Mode_Callback(app, event)
            app.Mode.UserData=rem(app.Mode.UserData+1,3);
            handles=SetGraphics_All(handles);
            handles=SetToolbar(handles);
            handles=SetPlay(handles);
            handles=Set12(handles,false);
            handles=Set34(handles);
            handles=Wait(handles,'off');
            handles=SetLayout(handles);
            guidata(hObject,handles);
        end

        % Button pushed function: Next
        function Next_Callback(app, event)
            app.Data.j=1;
            app.One_Pop.Value=app.One_Pop.Value+1;
            handles=SetGraphics_All(handles);
            handles=SetToolbar(handles);
            handles=SetPlay(handles);
            handles=Set12(handles,false);
            handles=Set34(handles);
            handles=Wait(handles,'off');
            handles=SetLayout(handles);
            handles=HT_DataAccess(handles,'read');
            guidata(hObject,handles);
        end

        % Value changed function: One_List
        function One_List_Callback(app, event)
            handles=Wait(handles,'on');
            handles=Set12(handles,false);
            handles=HT_DataAccess(handles,'prepare');
            handles=SetGraphics_All(handles);
            handles=SetToolbar(handles);
            handles=SetPlay(handles);
            handles=Set12(handles,false);
            handles=Set34(handles);
            handles=Wait(handles,'off');
            guidata(hObject,handles);
        end

        % Value changed function: One_Pop
        function One_Pop_Callback(app, event)
            handles=Wait(handles,'on');
            handles=Set12(handles,false);
            app.One_List.Value=1;
            handles=HT_DataAccess(handles,'read');
            handles=HT_Compute(handles);
            handles=SetView(handles,false);
            handles=SetGraphics_All(handles);
            handles=SetToolbar(handles);
            handles=SetPlay(handles);
            handles=Set12(handles,false);
            handles=Set34(handles);
            handles=Wait(handles,'off');
            guidata(hObject,handles);
        end

        % Button pushed function: PlayMode
        function PlayMode_Callback(app, event)
            app.PlayMode.UserData=rem(app.PlayMode.UserData+1,4);
            handles=SetGraphics_All(handles);
            handles=SetToolbar(handles);
            handles=SetPlay(handles);
            handles=Set12(handles,false);
            handles=Set34(handles);
            handles=Wait(handles,'off');
            handles=SetLayout(handles);
            guidata(hObject,handles);
        end

        % Button pushed function: Play
        function Play_Callback(app, event)
            if app.Data.Audio.isplaying
                app.Data.Audio.pause;
            elseif hObject.UserData==1
                hObject.UserData=0;
            else
                hObject.UserData=1;
                hObject.CData=app.icons.Pause;
                flag=false;
                while hObject.UserData==1
                    if app.PlayMode.UserData==0
                        app.Skip.UserData=0;
                        app.Back.UserData=0;
                        hObject.UserData=0;
                    else
                        if flag
                            if app.Data.j<length(app.Data.Edges)-2
                                app.Data.j=app.Data.j+1;
                                if app.PlayMode.UserData==1
                                    hObject.UserData=0;
                                end
                            elseif app.PlayMode.UserData==3
                                app.Data.j=1;
                            elseif app.QueueMode.Value==1
                                Next_Callback(app.Next, eventdata, handles);
                                app.Skip.UserData=0;
                            end
                            handles=HT_Compute(handles);
                            handles=SetView(handles,false);
                        end
                    end
                    if strcmp(app.Sound.State,'on')
                        app.Data.Audio.resume;
                        set(app.Graphics.Line(1:2),'Visible','on');
                        waitfor(app.Data.Audio,'Running','off');
                        set(app.Graphics.Line(1:2),'Visible','off');
                    end
                    flag=true;
                end
            end
            hObject.UserData=0;
            hObject.CData=app.icons.Play;
            guidata(hObject,handles);
        end

        % Button pushed function: Previous
        function Previous_Callback(app, event)
            if app.One_Pop.Value>2
                app.One_Pop.Value=app.One_Pop.Value-1;
                handles=SetGraphics_All(handles);
                handles=SetToolbar(handles);
                handles=SetPlay(handles);
                handles=Set12(handles,false);
                handles=Set34(handles);
                handles=Wait(handles,'off');
                handles=SetLayout(handles);
                app.Data.j=1;
                handles=HT_DataAccess(handles,'read');
                guidata(hObject,handles);
            end
        end

        % Value changed function: QueueMode
        function QueueMode_Callback(app, event)
            app.QueueMode.UserData=rem(app.QueueMode.UserData+1,2);
            handles=SetGraphics_All(handles);
            handles=SetToolbar(handles);
            handles=SetPlay(handles);
            handles=Set12(handles,false);
            handles=Set34(handles);
            handles=Wait(handles,'off');
            handles=SetLayout(handles);
            guidata(hObject,handles);
        end

        % Button pushed function: Reset
        function Reset_Callback(app, event)
            handles=SetAxis(handles,initialize);
            handles=Wait(handles,'off');
            app.Data.j=1;
            handles=SetLayout(handles,app.UserData.Frames);
            handles=HT_DataAccess(handles,'read');
            handles=HT_Compute(handles);
            handles=SetView(handles,false);
            guidata(hObject,handles);
        end

        % Button pushed function: Skip
        function Skip_Callback(app, event)
            hObject.UserData=1;
            if app.Data.j<length(app.Data.Bins)-1
                app.Data.j=app.Data.j+1;
            else
                app.Data.j=1;
            end
            handles=HT_Compute(handles);
            handles=SetView(handles,false);
            guidata(hObject,handles);
        end

        % Value changed function: Two_List
        function Two_List_Callback(app, event)
            value=app.Two_Pop.UserData{app.Two_Pop.Value,2}{hObject.Value,2};
            switch class(value)
                case 'logical'
                    app.Two_Slide.Value=double(value);
                case 'double'
                    app.Two_Slide.Value=value;
            end
            handles=Set12(handles,false);
            guidata(hObject,handles);
        end

        % Value changed function: Two_Pop
        function Two_Pop_Callback(app, event)
            app.Two_List.Value = 1;
            handles=Set12(handles,false);
            guidata(hObject,handles);
        end

        % Value changed function: Two_Slide
        function Two_Slide_Callback(app, event)
            handles=Wait(handles,'on');
            switch hObject.Style
                case 'slider'
                    temp=hObject.Value;
                case 'checkbox'
                    temp=logical(hObject.Value);
            end
            app.Two_Pop.UserData{app.Two_Pop.Value,2}...
                {app.Two_List.Value,2}=temp;
            handles=Set12(handles,false);
            handles=SetLayout(handles,app.UserData.Frames);
            handles=HT_DataAccess(handles,'read');
            handles=HT_Compute(handles);
            handles=SetView(handles,false);
            handles=SetGraphics_All(handles);
            handles=SetToolbar(handles);
            handles=SetPlay(handles);
            handles=Set12(handles,false);
            handles=Set34(handles);
            handles=Wait(handles,'off');
            guidata(hObject,handles);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Fig = uifigure;
            app.Fig.Name = 'Quail Kit (Beta)';
            app.Fig.SizeChangedFcn = createCallbackFcn(app, @Fig_SizeChangedFcn, true);
            app.Fig.KeyPressFcn = createCallbackFcn(app, @Fig_KeyPressFcn, true);
            app.Fig.KeyReleaseFcn = createCallbackFcn(app, @Fig_KeyReleaseFcn, true);
            app.Group45 = uipanel(app.Fig);
            app.Five = uibuttongroup(app.Group45);
            app.Five_Slide = uislider(app.Five);
            app.Five_Slide.Limits = [0 1];
            app.Five_Slide.MajorTicks = [];
            app.Five_Slide.ValueChangedFcn = createCallbackFcn(app, @Five_Slide_Callback, true);
            app.Five_Slide.MinorTicks = [];
            app.Five_List = uilistbox(app.Five);
            app.Five_List.Items = {};
            app.Five_List.ValueChangedFcn = createCallbackFcn(app, @Five_List_Callback, true);
            app.Five_List.Value = {};
            app.Five_Pop = uidropdown(app.Five);
            app.Five_Pop.Items = {'Detection Off', 'Template Matching'};
            app.Five_Pop.ValueChangedFcn = createCallbackFcn(app, @Five_Pop_Callback, true);
            app.Five_Pop.Value = 'Detection Off';
            app.Four = uibuttongroup(app.Group45);
            app.Four_List = uilistbox(app.Four);
            app.Four_List.Items = {};
            app.Four_List.ValueChangedFcn = createCallbackFcn(app, @Four_List_Callback, true);
            app.Four_List.Value = {};
            app.Four_Slide = uislider(app.Four);
            app.Four_Slide.Limits = [0 1];
            app.Four_Slide.MajorTicks = [];
            app.Four_Slide.ValueChangedFcn = createCallbackFcn(app, @Four_Slide_Callback, true);
            app.Four_Slide.MinorTicks = [];
            app.Four_Pop = uidropdown(app.Four);
            app.Four_Pop.Items = {'Visualization Off', 'Time/Frequency/Correlation'};
            app.Four_Pop.ValueChangedFcn = createCallbackFcn(app, @Four_Pop_Callback, true);
            app.Four_Pop.Value = 'Time/Frequency/Correlation';
            app.Group123 = uipanel(app.Fig);
            app.One = uibuttongroup(app.Group123);
            app.One_List = uilistbox(app.One);
            app.One_List.Items = {};
            app.One_List.ValueChangedFcn = createCallbackFcn(app, @One_List_Callback, true);
            app.One_List.Value = {};
            app.One_Pop = uidropdown(app.One);
            app.One_Pop.Items = {'Recording Set', 'All', 'Training', 'Testing'};
            app.One_Pop.ValueChangedFcn = createCallbackFcn(app, @One_Pop_Callback, true);
            app.One_Pop.Value = 'All';
            app.Two = uibuttongroup(app.Group123);
            app.Two_List = uilistbox(app.Two);
            app.Two_List.Items = {};
            app.Two_List.ValueChangedFcn = createCallbackFcn(app, @Two_List_Callback, true);
            app.Two_List.Value = {};
            app.Two_Slide = uislider(app.Two);
            app.Two_Slide.Limits = [0 1];
            app.Two_Slide.MajorTicks = [];
            app.Two_Slide.ValueChangedFcn = createCallbackFcn(app, @Two_Slide_Callback, true);
            app.Two_Slide.MinorTicks = [];
            app.Two_Pop = uidropdown(app.Two);
            app.Two_Pop.Items = {'Microphones', 'Annotations', 'Info'};
            app.Two_Pop.ValueChangedFcn = createCallbackFcn(app, @Two_Pop_Callback, true);
            app.Two_Pop.Value = 'Microphones';
            app.GroupPlay = uipanel(app.Fig);
            app.Previous = uibutton(app.GroupPlay, 'push');
            app.Previous.ButtonPushedFcn = createCallbackFcn(app, @Previous_Callback, true);
            app.Previous.Icon = 'Previous_image.png';
            app.Previous.Text = '';
            app.Back = uibutton(app.GroupPlay, 'push');
            app.Back.ButtonPushedFcn = createCallbackFcn(app, @Back_Callback, true);
            app.Back.Icon = 'Back_image.png';
            app.Back.Text = '';
            app.Clear = uibutton(app.GroupPlay, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @Clear_Callback, true);
            app.Clear.Icon = 'Clear_image.png';
            app.Clear.Text = '';
            app.Reset = uibutton(app.GroupPlay, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @Reset_Callback, true);
            app.Reset.Icon = 'Reset_image.png';
            app.Reset.Text = '';
            app.Skip = uibutton(app.GroupPlay, 'push');
            app.Skip.ButtonPushedFcn = createCallbackFcn(app, @Skip_Callback, true);
            app.Skip.Icon = 'Skip_image.png';
            app.Skip.Text = '';
            app.Next = uibutton(app.GroupPlay, 'push');
            app.Next.ButtonPushedFcn = createCallbackFcn(app, @Next_Callback, true);
            app.Next.Icon = 'Next_image.png';
            app.Next.Text = '';
            app.QueueMode = uibutton(app.GroupPlay, 'state');
            app.QueueMode.ValueChangedFcn = createCallbackFcn(app, @QueueMode_Callback, true);
            app.QueueMode.Enable = 'off';
            app.QueueMode.Icon = 'QueueMode_image.png';
            app.QueueMode.Text = '';
            app.QueueMode.Value = true;
            app.PlayMode = uibutton(app.GroupPlay, 'push');
            app.PlayMode.ButtonPushedFcn = createCallbackFcn(app, @PlayMode_Callback, true);
            app.PlayMode.Icon = 'PlayMode_image.png';
            app.PlayMode.Text = '';
            app.Mode = uibutton(app.GroupPlay, 'push');
            app.Mode.ButtonPushedFcn = createCallbackFcn(app, @Mode_Callback, true);
            app.Mode.Icon = 'Mode_image.png';
            app.Mode.Text = '';
            app.Debug = uibutton(app.GroupPlay, 'push');
            app.Debug.ButtonPushedFcn = createCallbackFcn(app, @Debug_Callback, true);
            app.Debug.Icon = 'Debug_image.png';
            app.Debug.Enable = 'off';
            app.Debug.Text = '';
            app.Play = uibutton(app.GroupPlay, 'push');
            app.Play.ButtonPushedFcn = createCallbackFcn(app, @Play_Callback, true);
            app.Play.Icon = 'Play_image.png';
            app.Play.Text = '';
            app.Panel = uipanel(app.Fig);
        end
    end

    methods (Access = public)

        % Construct app
        function app = QuailKit_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Fig)

            % Execute the startup function
            runStartupFcn(app, @QuailKit_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Fig)
        end
    end
end